classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DesenharMenu                    matlab.ui.container.Menu
        SimulaodeClculosPanel           matlab.ui.container.Panel
        CalcularButton                  matlab.ui.control.Button
        redeBtn                         matlab.ui.control.Button
        redeEF                          matlab.ui.control.EditField
        CaminhoparaaredeEditFieldLabel  matlab.ui.control.Label
        panelCalculo                    matlab.ui.container.Panel
        calculoLbl                      matlab.ui.control.Label
        pathLabel_2                     matlab.ui.control.Label
        num1EF                          matlab.ui.control.EditField
        Numero1EditFieldLabel           matlab.ui.control.Label
        opBtn                           matlab.ui.control.Button
        num2Btn                         matlab.ui.control.Button
        num1Btn                         matlab.ui.control.Button
        opEF                            matlab.ui.control.EditField
        OperadorEditFieldLabel          matlab.ui.control.Label
        pathLabel                       matlab.ui.control.Label
        num2EF                          matlab.ui.control.EditField
        Numero2Label                    matlab.ui.control.Label
        ResultadosPanel                 matlab.ui.container.Panel
        CriaodaRedeNeuronalPanel        matlab.ui.container.Panel
        CriarRedeButton                 matlab.ui.control.Button
        InputsPanel                     matlab.ui.container.Panel
        nomeRedeEF                      matlab.ui.control.EditField
        NomedaRedeEditFieldLabel        matlab.ui.control.Label
        pastaDD                         matlab.ui.control.DropDown
        PastaparaTreinoDropDownLabel    matlab.ui.control.Label
        ConfiguraodaRedePanel           matlab.ui.container.Panel
        testEF                          matlab.ui.control.NumericEditField
        testEditFieldLabel              matlab.ui.control.Label
        valEF                           matlab.ui.control.NumericEditField
        valEditFieldLabel               matlab.ui.control.Label
        trainEF                         matlab.ui.control.NumericEditField
        trainEditFieldLabel             matlab.ui.control.Label
        funcDivisaoDD                   matlab.ui.control.DropDown
        FunodeDivisoDropDownLabel       matlab.ui.control.Label
        funcAtivacao2DD                 matlab.ui.control.DropDown
        FunodeAtivao2DropDownLabel      matlab.ui.control.Label
        funcAtivacao1DD                 matlab.ui.control.DropDown
        FunodeAtivao1DropDownLabel      matlab.ui.control.Label
        funcTreinoDD                    matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel       matlab.ui.control.Label
        SimulaocomDatasetPanel          matlab.ui.container.Panel
        SimularcomDatasetButton         matlab.ui.control.Button
        panelDatasetResult              matlab.ui.container.Panel
        InputsPanel_2                   matlab.ui.container.Panel
        btnRedeDataset                  matlab.ui.control.Button
        pastaDD_2                       matlab.ui.control.DropDown
        PastaparaTreinoDropDownLabel_2  matlab.ui.control.Label
        nomeRedeDatasetEF               matlab.ui.control.EditField
        CaminhodaRedeLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: num1Btn
        function num1BtnButtonPushed(app, event)
            % Callback para selecionar a imagem do numero 1
            [file,path] = uigetfile('*.png');
            if isequal(file,0)
               disp('[Numero 1] O utilizador selecionou Cancelar');
            else
               disp(['[Numero 1] O utilizador selecionou "', fullfile(path,file), '"']);
               app.num1EF.Value = fullfile(path,file);
               app.num1EF.BackgroundColor = 'white';
            end
        end

        % Button pushed function: num2Btn
        function num2BtnButtonPushed(app, event)
            % Callback para selecionar a imagem do numero 2
            [file,path] = uigetfile('*.png');
            if isequal(file,0)
               disp('[Numero 2] O utilizador selecionou "Cancelar"');
            else
               disp(['[Numero 2] O utilizador selecionou "', fullfile(path,file), '"']);
               app.num2EF.Value = fullfile(path,file);
               app.num2EF.BackgroundColor = 'white';
            end
        end

        % Button pushed function: opBtn
        function opBtnButtonPushed(app, event)
            % Callback para selecionar a imagem do operador
            [file,path] = uigetfile('*.png');
            if isequal(file,0)
               disp('[Operador] O utilizador selecionou Cancelar');
            else
               disp(['[Operador] O utilizador selecionou "', fullfile(path,file), '"']);
               app.opEF.Value = fullfile(path,file);
               app.opEF.BackgroundColor = 'white';
            end
        end

        % Button pushed function: redeBtn
        function redeBtnButtonPushed(app, event)
            % Callback para selecionar a rede previamente treinada
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               disp('[Rede] O utilizador selecionou Cancelar');
            else
               disp(['[Rede] O utilizador selecionou "', fullfile(path,file), '"']);
               app.redeEF.Value = fullfile(path,file);
               app.redeEF.BackgroundColor = 'white';
            end
        end

        % Button pushed function: btnRedeDataset
        function btnRedeDatasetButtonPushed(app, event)
            % Callback para selecionar a rede previamente treinada
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               disp('[Rede] O utilizador selecionou Cancelar');
            else
               disp(['[Rede] O utilizador selecionou "', fullfile(path,file), '"']);
               app.nomeRedeDatasetEF.Value = fullfile(path,file);
            end
        end

        % Menu selected function: DesenharMenu
        function DesenharMenuSelected(app, event)
            % Opcao para desenhar uma expressao
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %% Calcular a expressão baseado nas imagens escolhidas
            % Verificacoes para a selecao das imagens e rede
            if isempty(app.num1EF.Value)
                disp('[ERRO] Deve selecionar a imagem do numero 1');
                app.num1EF.BackgroundColor = 'red';
            elseif isempty(app.num2EF.Value)
                disp('[ERRO] Deve selecionar a imagem do numero 2');
                app.num2EF.BackgroundColor = 'red';
            elseif isempty(app.opEF.Value)
                disp('[ERRO] Deve selecionar a imagem do operador');             
                app.opEF.BackgroundColor = 'red';
            elseif isempty(app.redeEF.Value)
                disp('[ERRO] Deve selecionar a rede previamente treinada');             
                app.redeEF.BackgroundColor = 'red';
            else
                % Obter os digitos extraidos da imagem
                num1 = trainFunction_d(app.num1EF.Value,app.redeEF.Value);
                num2 = trainFunction_d(app.num2EF.Value,app.redeEF.Value);
                op = trainFunction_d(app.opEF.Value,app.redeEF.Value);
                
                str = strcat(num1,op,num2,'=');
                switch(op)
                    case '+'
                        app.calculoLbl.Text = strcat(str,num2str(str2num(num1) + str2num(num2)));
                    case '-'
                        app.calculoLbl.Text = strcat(str,num2str(str2num(num1) - str2num(num2)));
                    case '*'
                        app.calculoLbl.Text = strcat(str,num2str(str2num(num1) * str2num(num2)));
                    case '/'
                        app.calculoLbl.Text = strcat(str,num2str(str2num(num1) / str2num(num2)));
                end  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DesenharMenu = uimenu(app.UIFigure);
            app.DesenharMenu.MenuSelectedFcn = createCallbackFcn(app, @DesenharMenuSelected, true);
            app.DesenharMenu.Text = 'Desenhar';
            app.SimulaocomDatasetPanel = uipanel(app.UIFigure);
            app.SimulaocomDatasetPanel.Title = 'Simulação com Dataset';
            app.InputsPanel_2 = uipanel(app.SimulaocomDatasetPanel);
            app.InputsPanel_2.Title = 'Inputs';
            app.CaminhodaRedeLabel = uilabel(app.InputsPanel_2);
            app.CaminhodaRedeLabel.Text = 'Caminho da Rede';
            app.nomeRedeDatasetEF = uieditfield(app.InputsPanel_2, 'text');
            app.PastaparaTreinoDropDownLabel_2 = uilabel(app.InputsPanel_2);
            app.PastaparaTreinoDropDownLabel_2.Text = 'Pasta para Treino';
            app.pastaDD_2 = uidropdown(app.InputsPanel_2);
            app.pastaDD_2.Items = {'start', 'train'};
            app.pastaDD_2.Value = 'start';
            app.btnRedeDataset = uibutton(app.InputsPanel_2, 'push');
            app.btnRedeDataset.ButtonPushedFcn = createCallbackFcn(app, @btnRedeDatasetButtonPushed, true);
            app.btnRedeDataset.Text = 'Pesquisar';
            app.panelDatasetResult = uipanel(app.SimulaocomDatasetPanel);
            app.panelDatasetResult.Title = 'Resultados';
            app.SimularcomDatasetButton = uibutton(app.SimulaocomDatasetPanel, 'push');
            app.SimularcomDatasetButton.Text = 'Simular com Dataset';
            app.CriaodaRedeNeuronalPanel = uipanel(app.UIFigure);
            app.CriaodaRedeNeuronalPanel.Title = 'Criação da Rede Neuronal';
            app.ConfiguraodaRedePanel = uipanel(app.CriaodaRedeNeuronalPanel);
            app.ConfiguraodaRedePanel.Title = 'Configuração da Rede';
            app.FunodeTreinoDropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino';
            app.funcTreinoDD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcTreinoDD.Items = {'trainlm', 'trainbfg', 'trainrp', 'trainscg', 'traincgb', 'traincgf', 'traincgp', 'trainoss', 'traingdx'};
            app.funcTreinoDD.Value = 'trainlm';
            app.FunodeAtivao1DropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeAtivao1DropDownLabel.Text = 'Função de Ativação 1';
            app.funcAtivacao1DD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcAtivacao1DD.Items = {'tansig', 'logsig', 'purelin'};
            app.funcAtivacao1DD.Value = 'tansig';
            app.FunodeAtivao2DropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeAtivao2DropDownLabel.Text = 'Função de Ativação 2';
            app.funcAtivacao2DD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcAtivacao2DD.Items = {'tansig', 'logsig', 'purelin'};
            app.funcAtivacao2DD.Value = 'tansig';
            app.FunodeDivisoDropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeDivisoDropDownLabel.Text = 'Função de Divisão';
            app.funcDivisaoDD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcDivisaoDD.Items = {'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.funcDivisaoDD.Value = 'dividerand';
            app.trainEditFieldLabel = uilabel(app.ConfiguraodaRedePanel);
            app.trainEditFieldLabel.Text = 'train';
            app.trainEF = uieditfield(app.ConfiguraodaRedePanel, 'numeric');
            app.trainEF.Value = 0.7;
            app.valEditFieldLabel = uilabel(app.ConfiguraodaRedePanel);
            app.valEditFieldLabel.Text = 'val';
            app.valEF = uieditfield(app.ConfiguraodaRedePanel, 'numeric');
            app.valEF.Value = 0.15;
            app.testEditFieldLabel = uilabel(app.ConfiguraodaRedePanel);
            app.testEditFieldLabel.Text = 'test';
            app.testEF = uieditfield(app.ConfiguraodaRedePanel, 'numeric');
            app.testEF.Value = 0.15;
            app.InputsPanel = uipanel(app.CriaodaRedeNeuronalPanel);
            app.InputsPanel.Title = 'Inputs';
            app.PastaparaTreinoDropDownLabel = uilabel(app.InputsPanel);
            app.PastaparaTreinoDropDownLabel.Text = 'Pasta para Treino';
            app.pastaDD = uidropdown(app.InputsPanel);
            app.pastaDD.Items = {'start', 'train'};
            app.pastaDD.Value = 'start';
            app.NomedaRedeEditFieldLabel = uilabel(app.InputsPanel);
            app.NomedaRedeEditFieldLabel.Text = 'Nome da Rede';
            app.nomeRedeEF = uieditfield(app.InputsPanel, 'text');
            app.CriarRedeButton = uibutton(app.CriaodaRedeNeuronalPanel, 'push');
            app.CriarRedeButton.Text = 'Criar Rede';
            app.ResultadosPanel = uipanel(app.UIFigure);
            app.ResultadosPanel.Title = 'Resultados';
            app.SimulaodeClculosPanel = uipanel(app.UIFigure);
            app.SimulaodeClculosPanel.Title = 'Simulação de Cálculos';
            app.Numero2Label = uilabel(app.SimulaodeClculosPanel);
            app.Numero2Label.Text = 'Numero 2';
            app.num2EF = uieditfield(app.SimulaodeClculosPanel, 'text');
            app.pathLabel = uilabel(app.SimulaodeClculosPanel);
            app.pathLabel.Text = 'Caminho para o ficheiro';
            app.OperadorEditFieldLabel = uilabel(app.SimulaodeClculosPanel);
            app.OperadorEditFieldLabel.Text = 'Operador';
            app.opEF = uieditfield(app.SimulaodeClculosPanel, 'text');
            app.num1Btn = uibutton(app.SimulaodeClculosPanel, 'push');
            app.num1Btn.ButtonPushedFcn = createCallbackFcn(app, @num1BtnButtonPushed, true);
            app.num1Btn.Text = 'Pesquisar';
            app.num2Btn = uibutton(app.SimulaodeClculosPanel, 'push');
            app.num2Btn.ButtonPushedFcn = createCallbackFcn(app, @num2BtnButtonPushed, true);
            app.num2Btn.Text = 'Pesquisar';
            app.opBtn = uibutton(app.SimulaodeClculosPanel, 'push');
            app.opBtn.ButtonPushedFcn = createCallbackFcn(app, @opBtnButtonPushed, true);
            app.opBtn.Text = 'Pesquisar';
            app.Numero1EditFieldLabel = uilabel(app.SimulaodeClculosPanel);
            app.Numero1EditFieldLabel.Text = 'Numero 1';
            app.num1EF = uieditfield(app.SimulaodeClculosPanel, 'text');
            app.pathLabel_2 = uilabel(app.SimulaodeClculosPanel);
            app.pathLabel_2.Text = 'Rede Neuronal';
            app.panelCalculo = uipanel(app.SimulaodeClculosPanel);
            app.panelCalculo.Title = 'Resultados';
            app.calculoLbl = uilabel(app.panelCalculo);
            app.calculoLbl.Text = '';
            app.CaminhoparaaredeEditFieldLabel = uilabel(app.SimulaodeClculosPanel);
            app.CaminhoparaaredeEditFieldLabel.Text = 'Caminho para a rede';
            app.redeEF = uieditfield(app.SimulaodeClculosPanel, 'text');
            app.redeBtn = uibutton(app.SimulaodeClculosPanel, 'push');
            app.redeBtn.ButtonPushedFcn = createCallbackFcn(app, @redeBtnButtonPushed, true);
            app.redeBtn.Text = 'Pesquisar';
            app.CalcularButton = uibutton(app.SimulaodeClculosPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end